classdef leaf_reflectance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        CabSliderLabel  matlab.ui.control.Label
        CabSlider       matlab.ui.control.Slider
        CwSliderLabel   matlab.ui.control.Label
        CwSlider        matlab.ui.control.Slider
        StartButton     matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        optipar % Description
        leafbio
        spectral
        refl0 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CabSlider
        function CabSliderValueChanged(app, event)
            app.leafbio.Cab = app.CabSlider.Value;
            hold(app.UIAxes, 'off')
            plot(app.UIAxes, app.spectral.wlP, app.refl0, 'kx-')
            hold(app.UIAxes, 'on')
            x = models.fluspect_lite(app.spectral,app.leafbio,app.optipar);
            plot(app.UIAxes, app.spectral.wlP, x.refl, 'x-', 'Color', [0.4660, 0.6740, 0.1880])
            legend(app.UIAxes, {'Cab=40, Cw=0.009', sprintf('Cab=%.0f, Cw=%.3f', app.leafbio.Cab, app.leafbio.Cw)})
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.spectral = struct();
            app.spectral.wlP = 400:1:2400;                      % PROSPECT data range
            app.spectral.wlE = 400:1:750;                       % excitation in E-F matrix
            app.spectral.wlF = 640:1:850;                       % chlorophyll fluorescence in E-F matrix
        
            %% fixed input for FLUSPECT and BSM
            x = load(fullfile('..', 'input', 'fluspect_data', 'Optipar2017_ProspectD'));
            app.optipar = x.optipar;
            
            app.leafbio.Cab = 40;
            app.leafbio.Cca = 25;
            app.leafbio.V2Z = 0;
            app.leafbio.Cw = 0.009;
            app.leafbio.Cdm = 0.012;
            app.leafbio.Cs = 0;
            app.leafbio.Cant = 1;
            app.leafbio.N = 1.4;
            app.leafbio.fqe = 0;
            
            x = models.fluspect_lite(app.spectral,app.leafbio,app.optipar);
            app.refl0 = x.refl;
            hold(app.UIAxes, 'off')
            plot(app.UIAxes, app.spectral.wlP, app.refl0, 'kx')
            legend(app.UIAxes, {'Cab=40, Cw=0.009'})
%             hold(app.UIAxes, 'on')
        end

        % Value changed function: CwSlider
        function CwSliderValueChanged(app, event)
            app.leafbio.Cw = app.CwSlider.Value;
            hold(app.UIAxes, 'off')
            plot(app.UIAxes, app.spectral.wlP, app.refl0, 'kx-')
            hold(app.UIAxes, 'on')
            x = models.fluspect_lite(app.spectral,app.leafbio,app.optipar);
            plot(app.UIAxes, app.spectral.wlP, x.refl, 'bx-')
            legend(app.UIAxes, {'Cab=40, Cw=0.009', sprintf('Cab=%.0f, Cw=%.3f', app.leafbio.Cab, app.leafbio.Cw)})
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Leaf reflectance')
            xlabel(app.UIAxes, 'wavelength, nm')
            ylabel(app.UIAxes, 'reflectance')
            app.CabSliderLabel = uilabel(app.UIFigure);
            app.CabSliderLabel.Text = 'Cab';
            app.CabSlider = uislider(app.UIFigure);
            app.CabSlider.ValueChangedFcn = createCallbackFcn(app, @CabSliderValueChanged, true);
            app.CabSlider.Value = 40;
            app.CwSliderLabel = uilabel(app.UIFigure);
            app.CwSliderLabel.Text = 'Cw';
            app.CwSlider = uislider(app.UIFigure);
            app.CwSlider.Limits = [0 0.2];
            app.CwSlider.ValueChangedFcn = createCallbackFcn(app, @CwSliderValueChanged, true);
            app.CwSlider.Value = 0.009;
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = leaf_reflectance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end